classdef FrequencyResponse < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        DirectoryEditField         matlab.ui.control.EditField
        DirectoryEditFieldLabel    matlab.ui.control.Label
        SelectButton               matlab.ui.control.Button
        FileGenerationCheckBox     matlab.ui.control.CheckBox
        RunButton                  matlab.ui.control.Button
        STOPEditField              matlab.ui.control.NumericEditField
        STOPEditFieldLabel         matlab.ui.control.Label
        STARTEditField             matlab.ui.control.NumericEditField
        STARTEditFieldLabel        matlab.ui.control.Label
        s1pFileEditField           matlab.ui.control.EditField
        s1pFileEditFieldLabel      matlab.ui.control.Label
        s1pFileEditField_2         matlab.ui.control.EditField
        s1pFileEditField_2Label    matlab.ui.control.Label
        s1pFileEditField_3         matlab.ui.control.EditField
        s1pFileEditField_3Label    matlab.ui.control.Label
        FrequencyLabel             matlab.ui.control.Label
        Comp2Label                 matlab.ui.control.Label
        Comp1Label                 matlab.ui.control.Label
        ReferenceLabel             matlab.ui.control.Label
        PlotTypeButtonGroup        matlab.ui.container.ButtonGroup
        EnableCheckBox_5           matlab.ui.control.CheckBox
        EnableCheckBox_4           matlab.ui.control.CheckBox
        ohmLabel                   matlab.ui.control.Label
        dBLabel                    matlab.ui.control.Label
        LineRealEditField          matlab.ui.control.NumericEditField
        LineRealEditFieldLabel     matlab.ui.control.Label
        LimitLineEditField         matlab.ui.control.NumericEditField
        LimitLineEditFieldLabel    matlab.ui.control.Label
        EnableCheckBox_3           matlab.ui.control.CheckBox
        LimitLineEditField_2       matlab.ui.control.NumericEditField
        LimitLineEditField_2Label  matlab.ui.control.Label
        Z11RealImagButton          matlab.ui.control.RadioButton
        S11LOGMAGButton            matlab.ui.control.RadioButton
        VSWRButton                 matlab.ui.control.RadioButton
        EnableCheckBox_2           matlab.ui.control.CheckBox
        EnableCheckBox             matlab.ui.control.CheckBox
        LoadButton_3               matlab.ui.control.Button
        LoadButton_2               matlab.ui.control.Button
        LoadButton                 matlab.ui.control.Button
        RightPanel                 matlab.ui.container.Panel
        UIAxes                     matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [file_ref_filename, file_ref_path,  ~] = uigetfile('*.s1p');
            file_ref_path = string(file_ref_path);
            file_ref_filename = string(file_ref_filename);
            app.s1pFileEditField_3.Value = append(file_ref_path,file_ref_filename);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {567, 567};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {433, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LoadButton = uibutton(app.LeftPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.LoadButton_2 = uibutton(app.LeftPanel, 'push');
            app.LoadButton_2.Text = 'Load';
            app.LoadButton_3 = uibutton(app.LeftPanel, 'push');
            app.LoadButton_3.Text = 'Load';
            app.EnableCheckBox = uicheckbox(app.LeftPanel);
            app.EnableCheckBox.Text = 'Enable';
            app.EnableCheckBox_2 = uicheckbox(app.LeftPanel);
            app.EnableCheckBox_2.Text = 'Enable';
            app.PlotTypeButtonGroup = uibuttongroup(app.LeftPanel);
            app.PlotTypeButtonGroup.Title = 'Plot Type';
            app.VSWRButton = uiradiobutton(app.PlotTypeButtonGroup);
            app.VSWRButton.Text = 'VSWR';
            app.VSWRButton.Value = true;
            app.S11LOGMAGButton = uiradiobutton(app.PlotTypeButtonGroup);
            app.S11LOGMAGButton.Text = 'S11 - LOGMAG';
            app.Z11RealImagButton = uiradiobutton(app.PlotTypeButtonGroup);
            app.Z11RealImagButton.Text = 'Z11 - Real/Imag';
            app.LimitLineEditField_2Label = uilabel(app.PlotTypeButtonGroup);
            app.LimitLineEditField_2Label.Text = 'Limit Line';
            app.LimitLineEditField_2 = uieditfield(app.PlotTypeButtonGroup, 'numeric');
            app.LimitLineEditField_2.Limits = [1 Inf];
            app.LimitLineEditField_2.Value = 2;
            app.EnableCheckBox_3 = uicheckbox(app.PlotTypeButtonGroup);
            app.EnableCheckBox_3.Text = 'Enable';
            app.EnableCheckBox_3.Value = true;
            app.LimitLineEditFieldLabel = uilabel(app.PlotTypeButtonGroup);
            app.LimitLineEditFieldLabel.Text = 'Limit Line';
            app.LimitLineEditField = uieditfield(app.PlotTypeButtonGroup, 'numeric');
            app.LimitLineEditField.Limits = [-Inf 0];
            app.LimitLineEditField.Value = -10;
            app.LineRealEditFieldLabel = uilabel(app.PlotTypeButtonGroup);
            app.LineRealEditFieldLabel.Text = 'Line [Real]';
            app.LineRealEditField = uieditfield(app.PlotTypeButtonGroup, 'numeric');
            app.LineRealEditField.Value = 50;
            app.dBLabel = uilabel(app.PlotTypeButtonGroup);
            app.dBLabel.Text = 'dB';
            app.ohmLabel = uilabel(app.PlotTypeButtonGroup);
            app.ohmLabel.Text = 'ohm';
            app.EnableCheckBox_4 = uicheckbox(app.PlotTypeButtonGroup);
            app.EnableCheckBox_4.Text = 'Enable';
            app.EnableCheckBox_4.Value = true;
            app.EnableCheckBox_5 = uicheckbox(app.PlotTypeButtonGroup);
            app.EnableCheckBox_5.Text = 'Enable';
            app.EnableCheckBox_5.Value = true;
            app.ReferenceLabel = uilabel(app.LeftPanel);
            app.ReferenceLabel.Text = 'Reference';
            app.Comp1Label = uilabel(app.LeftPanel);
            app.Comp1Label.Text = 'Comp #1';
            app.Comp2Label = uilabel(app.LeftPanel);
            app.Comp2Label.Text = 'Comp #2';
            app.FrequencyLabel = uilabel(app.LeftPanel);
            app.FrequencyLabel.Text = 'Frequency';
            app.s1pFileEditField_3Label = uilabel(app.LeftPanel);
            app.s1pFileEditField_3Label.Text = '.s1p File';
            app.s1pFileEditField_3 = uieditfield(app.LeftPanel, 'text');
            app.s1pFileEditField_2Label = uilabel(app.LeftPanel);
            app.s1pFileEditField_2Label.Text = '.s1p File';
            app.s1pFileEditField_2 = uieditfield(app.LeftPanel, 'text');
            app.s1pFileEditFieldLabel = uilabel(app.LeftPanel);
            app.s1pFileEditFieldLabel.Text = '.s1p File';
            app.s1pFileEditField = uieditfield(app.LeftPanel, 'text');
            app.STARTEditFieldLabel = uilabel(app.LeftPanel);
            app.STARTEditFieldLabel.Text = 'START';
            app.STARTEditField = uieditfield(app.LeftPanel, 'numeric');
            app.STARTEditField.Limits = [1 Inf];
            app.STARTEditField.ValueDisplayFormat = '%5g';
            app.STARTEditField.Value = 1;
            app.STOPEditFieldLabel = uilabel(app.LeftPanel);
            app.STOPEditFieldLabel.Text = 'STOP';
            app.STOPEditField = uieditfield(app.LeftPanel, 'numeric');
            app.STOPEditField.Limits = [2 Inf];
            app.STOPEditField.ValueDisplayFormat = '%5g';
            app.STOPEditField.Value = 1700;
            app.RunButton = uibutton(app.LeftPanel, 'push');
            app.RunButton.Text = 'Run';
            app.FileGenerationCheckBox = uicheckbox(app.LeftPanel);
            app.FileGenerationCheckBox.Text = 'File Generation';
            app.FileGenerationCheckBox.Value = true;
            app.SelectButton = uibutton(app.LeftPanel, 'push');
            app.SelectButton.Text = 'Select';
            app.DirectoryEditFieldLabel = uilabel(app.LeftPanel);
            app.DirectoryEditFieldLabel.Text = 'Directory';
            app.DirectoryEditField = uieditfield(app.LeftPanel, 'text');
            app.DirectoryEditField.Value = '.\';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FrequencyResponse

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end