classdef RadiationPattern < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        ImportfunctionmenuLabel         matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        OverviewTab                     matlab.ui.container.Tab
        Image                           matlab.ui.control.Image
        SiteCalTab                      matlab.ui.container.Tab
        InterpolationStepMHzEditField   matlab.ui.control.NumericEditField
        InterpolationStepMHzEditFieldLabel  matlab.ui.control.Label
        SiteNameEditField               matlab.ui.control.EditField
        SiteNameEditFieldLabel          matlab.ui.control.Label
        ETS3115AntennaParameterEditField  matlab.ui.control.EditField
        ETS3115AntennaParameterEditFieldLabel  matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        GenerateSiteCalibrationvalueButton  matlab.ui.control.Button
        SelectButton_4                  matlab.ui.control.Button
        SelectButton_3                  matlab.ui.control.Button
        VNAGeneratedILFiles2pEditField  matlab.ui.control.EditField
        VNAGeneratedILFiles2pEditFieldLabel  matlab.ui.control.Label
        MeasuredCSVTab                  matlab.ui.container.Tab
        Label_3                         matlab.ui.control.Label
        DBTablenameEditField_2          matlab.ui.control.EditField
        DBTablenameEditField_2Label     matlab.ui.control.Label
        ETS3115DataSelectionEditField   matlab.ui.control.EditField
        ETS3115DataSelectionEditFieldLabel  matlab.ui.control.Label
        CreateDUTgainsdBiButton         matlab.ui.control.Button
        DBTablenameEditField            matlab.ui.control.EditField
        DBTablenameEditFieldLabel       matlab.ui.control.Label
        ImportCSVfilestoSQLitefileButton  matlab.ui.control.Button
        SelectButton_2                  matlab.ui.control.Button
        ImportFileDirectoryEditField    matlab.ui.control.EditField
        ImportFileDirectoryEditFieldLabel  matlab.ui.control.Label
        DetectedImportfilesListBox      matlab.ui.control.ListBox
        DetectedImportfilesListBoxLabel  matlab.ui.control.Label
        HFSSCSVTab                      matlab.ui.container.Tab
        Label_2                         matlab.ui.control.Label
        AngleTypeDropDown               matlab.ui.control.DropDown
        AngleTypeDropDownLabel          matlab.ui.control.Label
        csvfilecontainsmultiplefrequencydatapointsCheckBox  matlab.ui.control.CheckBox
        DBTablenameEditField_3          matlab.ui.control.EditField
        DBTablenameEditField_3Label     matlab.ui.control.Label
        DetectedImportfilesListBox_2    matlab.ui.control.ListBox
        DetectedImportfilesListBox_2Label  matlab.ui.control.Label
        ImportFileDirectoryEditField_2  matlab.ui.control.EditField
        ImportFileDirectoryEditField_2Label  matlab.ui.control.Label
        ImportCSVfilestoSQLitefileButton_2  matlab.ui.control.Button
        SelectButton_5                  matlab.ui.control.Button
        SelectButton                    matlab.ui.control.Button
        WorkingdirectoryEditField       matlab.ui.control.EditField
        WorkingdirectoryEditFieldLabel  matlab.ui.control.Label
        TargetSQLiteFileEditField       matlab.ui.control.EditField
        TargetSQLiteFileEditFieldLabel  matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            work_path = uigetdir();
            work_path = string(work_path);
            app.WorkingdirectoryEditField.Value = work_path;
        end

        % Button pushed function: SelectButton_2
        function SelectButton_2Pushed(app, event)
            [~,import_path,] = uigetfile('*.csv');
            import_path = string(import_path);
            app.ImportFileDirectoryEditField.Value = import_path;
            dir_cmd = append(import_path,'\*.csv');
            dir_csvfiles = dir(dir_cmd);
            csv_files = [];
            for(n=1:1:length(dir_csvfiles))
                csv_files = [csv_files; string(fullfile(dir_csvfiles(n).folder,dir_csvfiles(n).name));];
            end
            % App building.
            % https://www.mathworks.com/help/matlab/creating_guis/choose-components-for-your-app-designer-app.html

            app.DetectedImportfilesListBox.Items = csv_files;
        end

        % Button pushed function: ImportCSVfilestoSQLitefileButton
        function ImportCSVfilestoSQLitefileButtonPushed(app, event)
            app.ImportCSVfilestoSQLitefileButton.Enable = "off";

            database_file = string(append(app.WorkingdirectoryEditField.Value, '\', app.TargetSQLiteFileEditField.Value));
            csv_files = app.DetectedImportfilesListBox.Items;
            %csv_files = [csv_files{:}];
            csv_files = string(csv_files);
            table_name = string(app.DBTablenameEditField.Value);
            cmd_proc_import_measured_csv(database_file, csv_files, table_name);

            app.ImportCSVfilestoSQLitefileButton.Enable = "on";
        end

        % Value changed function: TargetSQLiteFileEditField
        function TargetSQLiteFileEditFieldValueChanged(app, event)
  
        end

        % Button pushed function: SelectButton_3
        function SelectButton_3Pushed(app, event)
            [meas_cal_file,meas_cal_path,] = uigetfile('*.s2p');
            meas_cal = string(append(meas_cal_path,meas_cal_file));
            app.VNAGeneratedILFiles2pEditField.Value = meas_cal;
        end

        % Button pushed function: SelectButton_4
        function SelectButton_4Pushed(app, event)
            [~,ets3115_path,~] = uigetfile('*.xlsx');
            app.ETS3115AntennaParameterEditField.Value = append(ets3115_path, '\');
        end

        % Button pushed function: GenerateSiteCalibrationvalueButton
        function GenerateSiteCalibrationvalueButtonPushed(app, event)
            app.GenerateSiteCalibrationvalueButton.Enable = "off";

            database_file = string(append(app.WorkingdirectoryEditField.Value, '\', app.TargetSQLiteFileEditField.Value));
            cmd_proc_import_sitedata(database_file,string(app.SiteNameEditField.Value), string(app.VNAGeneratedILFiles2pEditField.Value), app.InterpolationStepMHzEditField.Value);
            cmd_proc_import_antenna_ETS3115(database_file, string(app.ETS3115AntennaParameterEditField.Value), app.InterpolationStepMHzEditField.Value, false);
            cmd_proc_generate_sitecalval(database_file);
            disp(database_file);

            app.GenerateSiteCalibrationvalueButton.Enable = "on";
        end

        % Button pushed function: CreateDUTgainsdBiButton
        function CreateDUTgainsdBiButtonPushed(app, event)
            app.CreateDUTgainsdBiButton.Enable = "off";

            database_file = string(append(app.WorkingdirectoryEditField.Value, '\', app.TargetSQLiteFileEditField.Value));
            cmd_proc_generate_dutgain(database_file, string(app.DBTablenameEditField_2.Value), string(app.ETS3115DataSelectionEditField.Value));

            app.CreateDUTgainsdBiButton.Enable = "on";
        end

        % Button pushed function: SelectButton_5
        function SelectButton_5Pushed(app, event)
            [~,import_path,] = uigetfile('*.csv');
            import_path = string(import_path);
            app.ImportFileDirectoryEditField_2.Value = import_path;
            dir_cmd = append(import_path,'\*.csv');
            dir_csvfiles = dir(dir_cmd);
            csv_files = [];
            for(n=1:1:length(dir_csvfiles))
                csv_files = [csv_files; string(fullfile(dir_csvfiles(n).folder,dir_csvfiles(n).name));];
            end
            % App building.
            % https://www.mathworks.com/help/matlab/creating_guis/choose-components-for-your-app-designer-app.html

            app.DetectedImportfilesListBox_2.Items = csv_files;
        end

        % Button pushed function: ImportCSVfilestoSQLitefileButton_2
        function ImportCSVfilestoSQLitefileButton_2Pushed(app, event)
            app.ImportCSVfilestoSQLitefileButton_2.Enable = "off";

            csv_files = app.DetectedImportfilesListBox_2.Items;
            csv_files = string(csv_files);
            table_name = string(app.DBTablenameEditField_2.Value);

            database_file = string(append(app.WorkingdirectoryEditField.Value, '\', app.TargetSQLiteFileEditField.Value));
            if app.csvfilecontainsmultiplefrequencydatapointsCheckBox.Value == true
                disp("HFSS Multiple freqyency type selected.")
                cmd_proc_import_antrad_hfss_multi_csv(database_file, csv_files, table_name);
            else
                disp("HFSS Single freqyency type selected.")
                cmd_proc_import_antrad_hfss_csv(database_file, csv_files, table_name, string(app.AngleTypeDropDown.Value));
            end

            app.ImportCSVfilestoSQLitefileButton_2.Enable = "on";            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {538, 538};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {619, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TargetSQLiteFileEditFieldLabel = uilabel(app.LeftPanel);
            app.TargetSQLiteFileEditFieldLabel.Text = 'Target SQLite File';
            app.TargetSQLiteFileEditField = uieditfield(app.LeftPanel, 'text');
            app.TargetSQLiteFileEditField.ValueChangedFcn = createCallbackFcn(app, @TargetSQLiteFileEditFieldValueChanged, true);
            app.TargetSQLiteFileEditField.Value = 'sample_meas.db';
            app.WorkingdirectoryEditFieldLabel = uilabel(app.LeftPanel);
            app.WorkingdirectoryEditFieldLabel.Text = 'Working directory';
            app.WorkingdirectoryEditField = uieditfield(app.LeftPanel, 'text');
            app.WorkingdirectoryEditField.Value = '.';
            app.SelectButton = uibutton(app.LeftPanel, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.OverviewTab = uitab(app.TabGroup);
            app.OverviewTab.Title = 'Overview';
            app.Image = uiimage(app.OverviewTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'site_calibration_method.png');
            app.SiteCalTab = uitab(app.TabGroup);
            app.SiteCalTab.Title = 'Site Cal';
            app.VNAGeneratedILFiles2pEditFieldLabel = uilabel(app.SiteCalTab);
            app.VNAGeneratedILFiles2pEditFieldLabel.Text = 'VNA Generated IL File (.s2p)';
            app.VNAGeneratedILFiles2pEditField = uieditfield(app.SiteCalTab, 'text');
            app.VNAGeneratedILFiles2pEditField.Value = '(specify 20221126_SiteCal_ETS3115_HornBothSide.s2p)';
            app.SelectButton_3 = uibutton(app.SiteCalTab, 'push');
            app.SelectButton_3.ButtonPushedFcn = createCallbackFcn(app, @SelectButton_3Pushed, true);
            app.SelectButton_3.Text = 'Select';
            app.SelectButton_4 = uibutton(app.SiteCalTab, 'push');
            app.SelectButton_4.ButtonPushedFcn = createCallbackFcn(app, @SelectButton_4Pushed, true);
            app.SelectButton_4.Text = 'Select';
            app.GenerateSiteCalibrationvalueButton = uibutton(app.SiteCalTab, 'push');
            app.GenerateSiteCalibrationvalueButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateSiteCalibrationvalueButtonPushed, true);
            app.GenerateSiteCalibrationvalueButton.Text = 'Generate Site-Calibration value';
            app.Label = uilabel(app.SiteCalTab);
            app.Label.Text = {'''Site Cal'' helps to generate the site-calibration value in SQLite DB file.'; 'Set the measured .s2p file and Antenna parameter file. '; 'Then, click the button to generate the table in SQLite.'; 'Note1: Generated value is linear interpolated value with the frequency step below.'; 'Note2: Frequency point shall be exactly same with the data to be compensated.'};
            app.ETS3115AntennaParameterEditFieldLabel = uilabel(app.SiteCalTab);
            app.ETS3115AntennaParameterEditFieldLabel.Text = 'ETS3115 Antenna Parameter';
            app.ETS3115AntennaParameterEditField = uieditfield(app.SiteCalTab, 'text');
            app.ETS3115AntennaParameterEditField.Value = '(specify the directory contains TAR-3115.xlsx)';
            app.SiteNameEditFieldLabel = uilabel(app.SiteCalTab);
            app.SiteNameEditFieldLabel.Text = 'Site Name';
            app.SiteNameEditField = uieditfield(app.SiteCalTab, 'text');
            app.SiteNameEditField.Value = 'Site19_KIT';
            app.InterpolationStepMHzEditFieldLabel = uilabel(app.SiteCalTab);
            app.InterpolationStepMHzEditFieldLabel.Text = 'Interpolation Step [MHz]';
            app.InterpolationStepMHzEditField = uieditfield(app.SiteCalTab, 'numeric');
            app.InterpolationStepMHzEditField.Limits = [0.001 Inf];
            app.InterpolationStepMHzEditField.ValueDisplayFormat = '%.3f';
            app.InterpolationStepMHzEditField.Editable = 'off';
            app.InterpolationStepMHzEditField.Value = 1;
            app.MeasuredCSVTab = uitab(app.TabGroup);
            app.MeasuredCSVTab.Title = 'Measured CSV';
            app.DetectedImportfilesListBoxLabel = uilabel(app.MeasuredCSVTab);
            app.DetectedImportfilesListBoxLabel.Text = 'Detected Import files';
            app.DetectedImportfilesListBox = uilistbox(app.MeasuredCSVTab);
            app.DetectedImportfilesListBox.Items = {};
            app.DetectedImportfilesListBox.Value = {};
            app.ImportFileDirectoryEditFieldLabel = uilabel(app.MeasuredCSVTab);
            app.ImportFileDirectoryEditFieldLabel.Text = 'Import File Directory';
            app.ImportFileDirectoryEditField = uieditfield(app.MeasuredCSVTab, 'text');
            app.SelectButton_2 = uibutton(app.MeasuredCSVTab, 'push');
            app.SelectButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectButton_2Pushed, true);
            app.SelectButton_2.Text = 'Select';
            app.ImportCSVfilestoSQLitefileButton = uibutton(app.MeasuredCSVTab, 'push');
            app.ImportCSVfilestoSQLitefileButton.ButtonPushedFcn = createCallbackFcn(app, @ImportCSVfilestoSQLitefileButtonPushed, true);
            app.ImportCSVfilestoSQLitefileButton.Text = 'Import CSV files to SQLite file';
            app.DBTablenameEditFieldLabel = uilabel(app.MeasuredCSVTab);
            app.DBTablenameEditFieldLabel.Text = 'DB Table name';
            app.DBTablenameEditField = uieditfield(app.MeasuredCSVTab, 'text');
            app.DBTablenameEditField.Value = 'dut_meas';
            app.CreateDUTgainsdBiButton = uibutton(app.MeasuredCSVTab, 'push');
            app.CreateDUTgainsdBiButton.ButtonPushedFcn = createCallbackFcn(app, @CreateDUTgainsdBiButtonPushed, true);
            app.CreateDUTgainsdBiButton.Text = 'Create DUT gains [dBi]';
            app.ETS3115DataSelectionEditFieldLabel = uilabel(app.MeasuredCSVTab);
            app.ETS3115DataSelectionEditFieldLabel.Text = 'ETS3115 Data Selection';
            app.ETS3115DataSelectionEditField = uieditfield(app.MeasuredCSVTab, 'text');
            app.ETS3115DataSelectionEditField.Editable = 'off';
            app.ETS3115DataSelectionEditField.Value = 'site_calval_TAR3115_dataset_Data3mV';
            app.DBTablenameEditField_2Label = uilabel(app.MeasuredCSVTab);
            app.DBTablenameEditField_2Label.Text = 'DB Table name';
            app.DBTablenameEditField_2 = uieditfield(app.MeasuredCSVTab, 'text');
            app.DBTablenameEditField_2.Value = 'dut_gains';
            app.Label_3 = uilabel(app.MeasuredCSVTab);
            app.Label_3.Text = {'Specify the file directory.'; 'If the calibration table is ready in the specified Database file,'; 'execute Create DUT gains so that the absolute gain creates.'};
            app.HFSSCSVTab = uitab(app.TabGroup);
            app.HFSSCSVTab.Title = 'HFSS CSV';
            app.SelectButton_5 = uibutton(app.HFSSCSVTab, 'push');
            app.SelectButton_5.ButtonPushedFcn = createCallbackFcn(app, @SelectButton_5Pushed, true);
            app.SelectButton_5.Text = 'Select';
            app.ImportCSVfilestoSQLitefileButton_2 = uibutton(app.HFSSCSVTab, 'push');
            app.ImportCSVfilestoSQLitefileButton_2.ButtonPushedFcn = createCallbackFcn(app, @ImportCSVfilestoSQLitefileButton_2Pushed, true);
            app.ImportCSVfilestoSQLitefileButton_2.Text = 'Import CSV files to SQLite file';
            app.ImportFileDirectoryEditField_2Label = uilabel(app.HFSSCSVTab);
            app.ImportFileDirectoryEditField_2Label.Text = 'Import File Directory';
            app.ImportFileDirectoryEditField_2 = uieditfield(app.HFSSCSVTab, 'text');
            app.DetectedImportfilesListBox_2Label = uilabel(app.HFSSCSVTab);
            app.DetectedImportfilesListBox_2Label.Text = 'Detected Import files';
            app.DetectedImportfilesListBox_2 = uilistbox(app.HFSSCSVTab);
            app.DetectedImportfilesListBox_2.Items = {};
            app.DetectedImportfilesListBox_2.Value = {};
            app.DBTablenameEditField_3Label = uilabel(app.HFSSCSVTab);
            app.DBTablenameEditField_3Label.Text = 'DB Table name';
            app.DBTablenameEditField_3 = uieditfield(app.HFSSCSVTab, 'text');
            app.DBTablenameEditField_3.Value = 'dut_gain';
            app.csvfilecontainsmultiplefrequencydatapointsCheckBox = uicheckbox(app.HFSSCSVTab);
            app.csvfilecontainsmultiplefrequencydatapointsCheckBox.Text = '.csv file contains multiple frequency data points?';
            app.csvfilecontainsmultiplefrequencydatapointsCheckBox.Value = true;
            app.AngleTypeDropDownLabel = uilabel(app.HFSSCSVTab);
            app.AngleTypeDropDownLabel.Text = 'Angle Type';
            app.AngleTypeDropDown = uidropdown(app.HFSSCSVTab);
            app.AngleTypeDropDown.Items = {'theta', 'phi'};
            app.AngleTypeDropDown.Value = 'theta';
            app.Label_2 = uilabel(app.HFSSCSVTab);
            app.Label_2.Text = {'Specify the file directory to be loaded.'; 'Need to specify the csv file type. HFSS generates two different file format.'};
            app.ImportfunctionmenuLabel = uilabel(app.LeftPanel);
            app.ImportfunctionmenuLabel.Text = 'Import function menu';
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RadiationPattern

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end